classdef Pneumonia_Detection_Minimal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Pneumonia_Detection_UI_Figure  matlab.ui.Figure
        Probability_Label              matlab.ui.control.Label
        Classification_Label           matlab.ui.control.Label
        Upload_Button                  matlab.ui.control.Button
        Uploaded_CXR_Image             matlab.ui.control.Image
        ContextMenu                    matlab.ui.container.ContextMenu
        Export                         matlab.ui.container.Menu
        ResetProgramMenu               matlab.ui.container.Menu
        PoptoCenterMenu                matlab.ui.container.Menu
    end

    
    properties (Access = private)
        % Variable for Input Path
        inputPath; 
        
        % Initialize Networks 
        netResNet50;
        netDenseNet201;
        netXception;
        netResNet101;
    end
    
    methods (Access = private)
        
        function loadNetworks(app)
            load('ResNet50.mat','ResNet50');
            app.netResNet50 = ResNet50;

            load('DenseNet201.mat','DenseNet201');
            app.netDenseNet201 = DenseNet201;

            load('Xception.mat','Xception');
            app.netXception = Xception;

            load('ResNet101.mat','ResNet101');
            app.netResNet101 = ResNet101;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Center the Application on Screen
            app.Pneumonia_Detection_UI_Figure.Visible = 'off';
            movegui(app.Pneumonia_Detection_UI_Figure,"center")
            app.Pneumonia_Detection_UI_Figure.Visible = 'on';  
        end

        % Button pushed function: Upload_Button
        function Upload_ButtonPushed(app, event)
            % Reset Some UI Elements
            app.Classification_Label.Text = "Pneumonia Detection";
            app.Classification_Label.BackgroundColor = '0.80,0.80,0.80';
            app.Uploaded_CXR_Image.Tooltip = "Upload a chest x-ray with the button below.";
            app.inputPath = "";

            % File Selection Prompt
            [file,path] = uigetfile({'*.jpeg';'*.png'});
            if isequal(file,0)
                
            else
                % Image Selection
                app.inputPath = strcat(path,file);
                app.Uploaded_CXR_Image.ImageSource = app.inputPath;
                app.Uploaded_CXR_Image.Tooltip = app.inputPath;

                % Disable Upload Button Temporarily
                app.Upload_Button.Enable = 'off';

                % Notify User of Processing
                app.Probability_Label.BackgroundColor = '1.00,0.41,0.41'; % Gray
                app.Probability_Label.Text = "Processing, Please Wait ...";
                

                % Code Snippet to Bring Program Forward
                drawnow; figure(app.Pneumonia_Detection_UI_Figure);
                loadNetworks(app);

                % Image Preprocessing (Resize to 224 and 299)
                resizedInput = preprocess_with_resize(app.inputPath);
                resizedInput299 = preprocess_with_resize299(app.inputPath);

                % ResNet50 Classification
                [labelResNet50,probabilityResNet50] = classify(app.netResNet50,resizedInput);

                % DenseNet201 Classification
                [labelDenseNet201,probabilityDenseNet201] = classify(app.netDenseNet201,resizedInput);

                % Xception Classification
                [labelXception,probabilityXception] = classify(app.netXception,resizedInput299);

                % ResNet101 Classification
                [labelResNet101,probabilityResNet101] = classify(app.netResNet101,resizedInput);

                % Initialize Classification Variables and Arrays
                classificationMatrix = [labelResNet50, labelDenseNet201, labelXception, labelResNet101];
                probabilityMatrix = [probabilityResNet50; probabilityDenseNet201; probabilityXception; probabilityResNet101];
                yesPneumoniaCount = 0;
                noPneumoniaCount = 0;
                yesPneumoniaProbability = 0;
                noPneumoniaProbability = 0;
                
                % Summation and Average of Probabilities
                for index = 1:1:4
                    if isequal(classificationMatrix(index),"PNEUMONIA")
                        yesPneumoniaProbability = yesPneumoniaProbability + probabilityMatrix(index,2);
                        yesPneumoniaCount = yesPneumoniaCount + 1;
                    elseif isequal(classificationMatrix(index),"NORMAL")
                        noPneumoniaProbability = noPneumoniaProbability + probabilityMatrix(index,1);
                        noPneumoniaCount = noPneumoniaCount + 1;
                    end
                end
                averageYesPneumoniaProbability = yesPneumoniaProbability / yesPneumoniaCount;
                averageNoPneumoniaProbability = noPneumoniaProbability / noPneumoniaCount;
            
                % Display Final Classification
                if (averageYesPneumoniaProbability > averageNoPneumoniaProbability || isnan(averageNoPneumoniaProbability))
                    app.Classification_Label.Text = "Pneumonia Detected";
                    app.Classification_Label.BackgroundColor = '1.00,0.41,0.41'; % Red
                    app.Probability_Label.Text = "Probability: " + averageYesPneumoniaProbability*100 + "%";
                    app.Probability_Label.BackgroundColor = '0.80,0.80,0.80'; % Gray
                elseif (averageYesPneumoniaProbability < averageNoPneumoniaProbability || isnan(averageYesPneumoniaProbability))
                    app.Classification_Label.Text = "No Pneumonia Detected";
                    app.Classification_Label.BackgroundColor = '0.61,1.00,0.63'; % Green
                    app.Probability_Label.Text = "Probability: " + averageNoPneumoniaProbability*100 + "%"; 
                    app.Probability_Label.BackgroundColor = '0.80,0.80,0.80'; % Gray
                elseif (averageYesPneumoniaProbability == averageNoPneumoniaProbability || averageNoPneumoniaProbability == averageYesPneumoniaProbability)
                    app.Classification_Label.Text = "Inconclusive Results";
                    app.Classification_Label.BackgroundColor = '0.96,0.98,0.61'; % Yellow
                    app.Probability_Label.Text = "? ? ?";
                    app.Probability_Label.BackgroundColor = '0.80,0.80,0.80'; % Gray
                else
                    app.Classification_Label.Text = "Error";
                    app.Classification_Label.BackgroundColor = 'Red';
                    app.Probability_Label.Text = "Oops, something went wrong.";
                    app.Probability_Label.BackgroundColor = '0.80,0.80,0.80'; % Gray
                end
            end

            % Enable the Upload Button Again
            app.Upload_Button.Enable = 'on';
        end

        % Menu selected function: Export
        function ExportMenuSelected(app, event)
            % Date Time File Naming
            timeStamp = now;
            datetimeStamp = datetime(timeStamp,'ConvertFrom','datenum');
            fileName = strcat("PD_" + string(datetimeStamp));
            fileName = strrep(fileName,':','');
            fileName = strrep(fileName,' ','_');
            fileName = strcat(fileName,'.jpg');
            
            % Change Upload Button to Date Time Stamp
            app.Pneumonia_Detection_UI_Figure.Visible = 'off';
            app.Upload_Button.Text = string(datetimeStamp);

            % Exporting Screenshot of App as Output
            exportapp(app.Pneumonia_Detection_UI_Figure,fileName);

            % Revert Upload Button
            app.Upload_Button.Text = "Upload";
            app.Pneumonia_Detection_UI_Figure.Visible = 'on';
        end

        % Menu selected function: PoptoCenterMenu
        function PoptoCenterMenuSelected(app, event)
            startupFcn(app);
        end

        % Menu selected function: ResetProgramMenu
        function ResetProgramMenuSelected(app, event)
            app.Classification_Label.Text = "Pneumonia Detection";
            app.Classification_Label.BackgroundColor = '0.80,0.80,0.80';
            app.Probability_Label.Text = "- Awaiting Input Image -";
            app.Probability_Label.BackgroundColor = '0.80,0.80,0.80';
            app.Uploaded_CXR_Image.ImageSource = "cxr_placeholder.png";
            app.Uploaded_CXR_Image.Tooltip = "Upload a chest x-ray with the button below.";
            app.Upload_Button.Enable = 'on';
            app.inputPath = "";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Pneumonia_Detection_UI_Figure = uifigure('Visible', 'off');
            app.Pneumonia_Detection_UI_Figure.Name = 'Pneumonia Detection';
            app.Pneumonia_Detection_UI_Figure.Icon = 'logo.png';
            app.Uploaded_CXR_Image = uiimage(app.Pneumonia_Detection_UI_Figure);
            app.Uploaded_CXR_Image.Tooltip = {'Upload a chest x-ray with the button below.'};
            app.Uploaded_CXR_Image.ImageSource = 'cxr_placeholder.png';
            app.Upload_Button = uibutton(app.Pneumonia_Detection_UI_Figure, 'push');
            app.Upload_Button.ButtonPushedFcn = createCallbackFcn(app, @Upload_ButtonPushed, true);
            app.Upload_Button.Tooltip = {'Upload a chest x-ray in jpg or png format.'};
            app.Upload_Button.Text = 'Upload';
            app.Classification_Label = uilabel(app.Pneumonia_Detection_UI_Figure);
            app.Classification_Label.Text = 'Pneumonia Detection';
            app.Probability_Label = uilabel(app.Pneumonia_Detection_UI_Figure);
            app.Probability_Label.Text = '- Awaiting Input Image -';
            app.ContextMenu = uicontextmenu(app.Pneumonia_Detection_UI_Figure);
            app.Export = uimenu(app.ContextMenu);
            app.Export.MenuSelectedFcn = createCallbackFcn(app, @ExportMenuSelected, true);
            app.Export.Accelerator = '1';
            app.Export.Text = 'Export Results';
            app.ResetProgramMenu = uimenu(app.ContextMenu);
            app.ResetProgramMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetProgramMenuSelected, true);
            app.ResetProgramMenu.Accelerator = '2';
            app.ResetProgramMenu.Text = 'Reset Program';
            app.PoptoCenterMenu = uimenu(app.ContextMenu);
            app.PoptoCenterMenu.MenuSelectedFcn = createCallbackFcn(app, @PoptoCenterMenuSelected, true);
            app.PoptoCenterMenu.Accelerator = '3';
            app.PoptoCenterMenu.Text = 'Pop to Center';
            % Assign app.ContextMenu
            app.Pneumonia_Detection_UI_Figure.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.Pneumonia_Detection_UI_Figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Pneumonia_Detection_Minimal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Pneumonia_Detection_UI_Figure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Pneumonia_Detection_UI_Figure)
        end
    end
end